classdef HSG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        EndorderEditField            matlab.ui.control.NumericEditField
        EndorderEditFieldLabel       matlab.ui.control.Label
        StartorderEditField          matlab.ui.control.NumericEditField
        StartorderEditFieldLabel     matlab.ui.control.Label
        BasevoltageEditField         matlab.ui.control.NumericEditField
        BasevoltageEditFieldLabel    matlab.ui.control.Label
        BasefrequencyEditField       matlab.ui.control.NumericEditField
        BasefrequencyEditFieldLabel  matlab.ui.control.Label
        CreatesweepButton            matlab.ui.control.Button
        ExportButton                 matlab.ui.control.Button
        AmplitudeEditField           matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel      matlab.ui.control.Label
        HarmonicorderEditField       matlab.ui.control.NumericEditField
        HarmonicorderEditFieldLabel  matlab.ui.control.Label
        CreatesignalButton           matlab.ui.control.Button
        RightPanel                   matlab.ui.container.Panel
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CreatesignalButton
        function CreatesignalButtonPushed(app, event)
            f= app.BasefrequencyEditField.Value;
            overSampRate= 20000/f; 
            fs=overSampRate*f; 
            nCyl = 200; 
            t=0:1/fs:nCyl*1/f; 
            harOrder = app.HarmonicorderEditField.Value;
            harMag = (app.AmplitudeEditField.Value*app.BasevoltageEditField.Value)/100;  
            basevoltage = app.BasevoltageEditField.Value;
            
            phase = 0;

            
            x1=basevoltage*sin(2*pi*f*t+phase) + harMag*sin(2*pi*f*harOrder*t+phase);
            plot(app.UIAxes,x1);
            app.UIAxes.XLim = [0 1000];


            L = length(x1); 
            NFFT = L-1;
            X = fft(x1,NFFT);
            X2 = abs(X/NFFT);
            X1 = X2(1:NFFT/2+1);
            X1(2:end-1) = 2*X1(2:end-1);
            ff = fs*(0:(NFFT/2))/NFFT;

            plot(app.UIAxes2,ff,X1);
            app.UIAxes2.XLim = [0 2500];
            
            setappdata(app.UIFigure,'GlSignal',x1);

            line1 = "Harmonics,1,1998";
            line2 = "6,6A,0D";
            line3 = "1,V,L1,,V,0.01,0,0,-1000,1000,1,1,S";
            line4 = "2,V,L2,,V,0.0094949009470840575741767963791062,0,0,-32766,32766,1,1,S";
            line5 = "3,V,L3,,V,0.0094949009470841009422636957992836,0,0,-32766,32766,1,1,S";
            line6 = "4,I,L1,,A,3.0519440883843007572551186967758E-05,0,0,-32766,32766,1,1,S";
            line7 = "5,I,L2,,A,3.0519440883843007572551186967758E-05,0,0,-32766,32766,1,1,S";
            line8 = "6,I,L3,,A,3.0519440883843007572551186967758E-05,0,0,-32766,32766,1,1,S";
            line9 = "50";
            line10 = "1";
            line11 = "20000,40000";
            line12 = "30/08/2022,17:01:38.000000";
            line13 = "30/08/2022,17:01:38.000000";
            line14 = "ASCII";
            line15 = "1";
            configfile = {[line1]; [line2]; [line3]; [line4]; [line5];[line6];[line7];[line8];[line9];[line10];[line11];[line12];[line13];[line14];[line15]};  
            %; 
            fileID = fopen("config.txt",'w');
            formatSpec = '%s\n';
            [nrows,ncols] = size(configfile);
            for row = 1:nrows
                fprintf(fileID,formatSpec,configfile{row,:});
            end
            fclose(fileID);
            type config.txt;

            
            fileID = fopen('data.dat','w');
            formatSpec = '%s\n';
            formatSpec1 = '%d';
            formatSpec2 = '%s';
            nrows = 40000;
            ncol = 15;
            for row = 1:nrows+1
                datcol1 = row-1;
                Seperator = ",";
                datcol2 = (row-1)*100;
                datcol3 = x1(1,row)*100;
                datcol4 = 0;
                datcol5 = 0;
                datcol6 = 0;
                datcol7 = 0;
                datcol8 = 0;
                datline = {[datcol1];[Seperator];[datcol2];[Seperator];[datcol3];[Seperator];[datcol4];[Seperator];[datcol5];[Seperator];[datcol6];[Seperator];[datcol7];[Seperator];[datcol8]};
                for col = 1:ncol
                    
                        if mod(col,2) == 0
                            fprintf(fileID,formatSpec2,datline{col,:});
                        else
                            if col == 5
                                fprintf(fileID,'%.2f',datline{col,:});
                        
                            else
                                fprintf(fileID,formatSpec1,datline{col,:});
                            end
                        end
                    
                end
                fprintf(fileID,formatSpec,"");
            end
            fclose(fileID);
            type data.dat;


        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            line1 = "Harmonics,1,1998";
            line2 = "6,6A,0D";
            line3 = "1,V,L1,,V,0.01,0,0,-1000,1000,1,1,S";
            line4 = "2,V,L2,,V,0.0094949009470840575741767963791062,0,0,-32766,32766,1,1,S";
            line5 = "3,V,L3,,V,0.0094949009470841009422636957992836,0,0,-32766,32766,1,1,S";
            line6 = "4,I,L1,,A,3.0519440883843007572551186967758E-05,0,0,-32766,32766,1,1,S";
            line7 = "5,I,L2,,A,3.0519440883843007572551186967758E-05,0,0,-32766,32766,1,1,S";
            line8 = "6,I,L3,,A,3.0519440883843007572551186967758E-05,0,0,-32766,32766,1,1,S";
            line9 = "50";
            line10 = "1";
            line11 = "10000,10000";
            line12 = "30/08/2022,17:01:38.000000";
            line13 = "30/08/2022,17:01:38.000000";
            line14 = "ASCII";
            line15 = "1";
            configfile = {[line1]; [line2]; [line3]; [line4]; [line5];[line6];[line7];[line8];[line9];[line10];[line11];[line12];[line13];[line14];[line15]};  
            %; 
            fileID = fopen("config.txt",'w');
            formatSpec = '%s\n';
            [nrows,ncols] = size(configfile);
            for row = 1:nrows
                fprintf(fileID,formatSpec,configfile{row,:});
            end
            fclose(fileID);
            type config.txt;

            ExSignal = getappdata(app.UIFigure,'GlSignal');
            fileID = fopen('data.dat','w');
            formatSpec = '%s\n';
            formatSpec1 = '%d';
            formatSpec2 = '%s';
            nrows = 10000;
            ncol = 15;
            for row = 1:nrows
                datcol1 = row;
                Seperator = ",";
                datcol2 = row*100;
                datcol3 = ExSignal(1,row)*100;
                datcol4 = 0;
                datcol5 = 0;
                datcol6 = 0;
                datcol7 = 0;
                datcol8 = 0;
                datline = {[datcol1];[Seperator];[datcol2];[Seperator];[datcol3];[Seperator];[datcol4];[Seperator];[datcol5];[Seperator];[datcol6];[Seperator];[datcol7];[Seperator];[datcol8]};
                for col = 1:ncol
                    if mod(col,2) == 0
                        fprintf(fileID,formatSpec2,datline{col,:});
                    else
                    fprintf(fileID,formatSpec1,datline{col,:});
                    end
                end
                fprintf(fileID,formatSpec,"");
            end
            fclose(fileID);
            type data.dat;

           % 


        end

        % Button pushed function: CreatesweepButton
        function CreatesweepButtonPushed(app, event)
            f= app.BasefrequencyEditField.Value;
            overSampRate= 200; 
            fs=overSampRate*f; 
            nCyl = 500; 
            t=0:1/fs:nCyl*1/f; 
            harOrder = app.HarmonicorderEditField.Value;
            harMag = (app.AmplitudeEditField.Value*app.BasevoltageEditField.Value)/100;  
            basevoltage = app.BasevoltageEditField.Value;
            
            Sstart  = app.StartorderEditField.Value*50;
            Sstop = app.EndorderEditField.Value*50;
                  
           
            y = harMag*chirp(t,Sstart,1,Sstop);
            d = basevoltage*chirp(t,50,1,50);
            x5 = d + y;
            plot(app.UIAxes,x5);
            app.UIAxes.XLim = [0 8000];


            L = length(x5); 
            NFFT = L-1;
            X = fft(x5,NFFT);
            X2 = abs(X/NFFT);
            X1 = X2(1:NFFT/2+1);
            X1(2:end-1) = 2*X1(2:end-1);
            ff = fs*(0:(NFFT/2))/NFFT;

            plot(app.UIAxes2,ff,X1);
            app.UIAxes2.XLim = [0 2500];
            
            setappdata(app.UIFigure,'GlSignal',x5);

            line1 = "Harmonics,1,1998";
            line2 = "6,6A,0D";
            line3 = "1,V,L1,,V,0.01,0,0,-1000,1000,1,1,S";
            line4 = "2,V,L2,,V,0.0094949009470840575741767963791062,0,0,-32766,32766,1,1,S";
            line5 = "3,V,L3,,V,0.0094949009470841009422636957992836,0,0,-32766,32766,1,1,S";
            line6 = "4,I,L1,,A,3.0519440883843007572551186967758E-05,0,0,-32766,32766,1,1,S";
            line7 = "5,I,L2,,A,3.0519440883843007572551186967758E-05,0,0,-32766,32766,1,1,S";
            line8 = "6,I,L3,,A,3.0519440883843007572551186967758E-05,0,0,-32766,32766,1,1,S";
            line9 = app.BasefrequencyEditField.Value;
            line10 = "1";
            line11 = "10000,100000";
            line12 = "30/08/2022,17:01:38.000000";
            line13 = "30/08/2022,17:01:38.000000";
            line14 = "ASCII";
            line15 = "1";
            configfile = {[line1]; [line2]; [line3]; [line4]; [line5];[line6];[line7];[line8];[line9];[line10];[line11];[line12];[line13];[line14];[line15]};  
            %; 
            fileID = fopen("config.txt",'w');
            formatSpec = '%s\n';
            [nrows,ncols] = size(configfile);
            for row = 1:nrows
                fprintf(fileID,formatSpec,configfile{row,:});
            end
            fclose(fileID);
            type config.txt;

            
            fileID = fopen('data.dat','w');
            formatSpec = '%s\n';
            formatSpec1 = '%d';
            formatSpec2 = '%s';
            nrows = 100000;
            ncol = 15;
            for row = 1:nrows+1
                datcol1 = row-1;
                Seperator = ",";
                datcol2 = (row-1)*100;
                datcol3 = x5(1,row)*100;
                datcol4 = 0;
                datcol5 = 0;
                datcol6 = 0;
                datcol7 = 0;
                datcol8 = 0;
                datline = {[datcol1];[Seperator];[datcol2];[Seperator];[datcol3];[Seperator];[datcol4];[Seperator];[datcol5];[Seperator];[datcol6];[Seperator];[datcol7];[Seperator];[datcol8]};
                for col = 1:ncol
                    
                        if mod(col,2) == 0
                            fprintf(fileID,formatSpec2,datline{col,:});
                        else
                            if col == 5
                                fprintf(fileID,'%.2f',datline{col,:});
                        
                            else
                                fprintf(fileID,formatSpec1,datline{col,:});
                            end
                        end
                    
                end
                fprintf(fileID,formatSpec,"");
            end
            fclose(fileID);
            type data.dat;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CreatesignalButton = uibutton(app.LeftPanel, 'push');
            app.CreatesignalButton.ButtonPushedFcn = createCallbackFcn(app, @CreatesignalButtonPushed, true);
            app.CreatesignalButton.Text = 'Create signal';
            app.HarmonicorderEditFieldLabel = uilabel(app.LeftPanel);
            app.HarmonicorderEditFieldLabel.Text = 'Harmonic order';
            app.HarmonicorderEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AmplitudeEditFieldLabel = uilabel(app.LeftPanel);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude %';
            app.AmplitudeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ExportButton = uibutton(app.LeftPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.CreatesweepButton = uibutton(app.LeftPanel, 'push');
            app.CreatesweepButton.ButtonPushedFcn = createCallbackFcn(app, @CreatesweepButtonPushed, true);
            app.CreatesweepButton.Text = 'Create sweep';
            app.BasefrequencyEditFieldLabel = uilabel(app.LeftPanel);
            app.BasefrequencyEditFieldLabel.Text = 'Base frequency';
            app.BasefrequencyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.BasevoltageEditFieldLabel = uilabel(app.LeftPanel);
            app.BasevoltageEditFieldLabel.Text = 'Base voltage';
            app.BasevoltageEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StartorderEditFieldLabel = uilabel(app.LeftPanel);
            app.StartorderEditFieldLabel.Text = 'Start order';
            app.StartorderEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EndorderEditFieldLabel = uilabel(app.LeftPanel);
            app.EndorderEditFieldLabel.Text = 'End order';
            app.EndorderEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Generated signal (Zoomed)')
            xlabel(app.UIAxes, 'Sample nr')
            ylabel(app.UIAxes, 'Voltage')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Generated signal FFT')
            xlabel(app.UIAxes2, 'f (Hz)')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HSG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end